classdef FilterPlotting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        F0SliderLabel        matlab.ui.control.Label
        F0Slider             matlab.ui.control.Slider
        QFacSliderLabel      matlab.ui.control.Label
        QFacSlider           matlab.ui.control.Slider
        BoostSliderLabel     matlab.ui.control.Label
        BoostSlider          matlab.ui.control.Slider
        PlayButton           matlab.ui.control.Button
        kDropDown_2          matlab.ui.control.DropDown
        DurationSliderLabel  matlab.ui.control.Label
        DurationSlider       matlab.ui.control.Slider
        HzLabel              matlab.ui.control.Label
        dBLabel              matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        a
        b
        FileString = '';
        AudioFs
    end


    methods (Access = public)
        
        function FilterPlot(app, ~)
            %calculates and plots the frequency response of a bell filter given
            %sampling frequency, centre frequency and boost/cut
            Fs = 96000;
            F0 = app.F0Slider.Value;
            Qfac = app.QFacSlider.Value;
            boost = app.BoostSlider.Value;
            
            [app.b, app.a] = FilterCalc(app, Fs, F0, Qfac, boost);         
            
            [Hd, Fd] = freqz(app.b, app.a, 2^15, Fs); %get frequency response of discrete BZT transfer function
            
            plot(app.UIAxes, Fd, 20*log10(abs(Hd)), 'b') %Plot discrete BZT magnitude response
            

        end
    end
    
    methods (Access = public)
        
        function [b, a] = FilterCalc(~, Fs, F0, Qfac, boost)
            
            %calculate filter coefficients 
            G = 10^(boost/20); %calculate gain
            k = 3*((G -1)/(G + 1)); %calculate k
                    
            Wo =  2*pi*F0; %calculate centre frequency in Radians per second
            Wo2 = Wo^2; %centre frequency in Rads/s squared
            
            HSnum = [1 ((3+k)*(Wo/Qfac)) (Wo2)]; %calculate continuous transfer function numerator
            HSden = [1 ((3-k)*(Wo/Qfac)) (Wo2)]; %calculate continuous transfer function denominator
                 
            [b, a] = bilinear(HSnum, HSden, Fs, F0); %calc BZT coefficients for each iteration of the continuous TF            


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %calculates and plots the frequency response of a bell filter given
            %sampling frequency, centre frequency and boost/cut
            Fs = 96000;
            F0 = 8000;
            Qfac = 2;
            boost = 0;
            
            %calculate filter coefficients 
            G = 10^(boost/20); %calculate gain
            k = 3*((G -1)/(G + 1)); %calculate k
                    
            Wo =  2*pi*F0; %calculate centre frequency in Radians per second
            Wo2 = Wo^2; %centre frequency in Rads/s squared
            
            HSnum = [1 ((3+k)*(Wo/Qfac)) (Wo2)]; %calculate continuous transfer function numerator
            HSden = [1 ((3-k)*(Wo/Qfac)) (Wo2)]; %calculate continuous transfer function denominator
                 
            [Hznum, HZden] = bilinear(HSnum, HSden, Fs, F0); %calc BZT coefficients for each iteration of the continuous TF            
            
            [Hd, Fd] = freqz(Hznum, HZden, 2^15, Fs); %get frequency response of discrete BZT transfer function
            
            plot(app.UIAxes, Fd, 20*log10(abs(Hd)), 'b') %Plot discrete BZT magnitude response
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {560, 560};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {472, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: F0Slider
        function F0SliderValueChanged(app, event)
          
            FilterPlot(app, event);
            
        end

        % Value changing function: F0Slider
        function F0SliderValueChanging(app, event)
           
            FilterPlot(app, event);
            
        end

        % Value changing function: QFacSlider
        function QFacSliderValueChanging(app, event)
            
            FilterPlot(app, event);
            
        end

        % Value changing function: BoostSlider
        function BoostSliderValueChanging(app, event)
           
            FilterPlot(app, event);
            
        end

        % Value changed function: BoostSlider
        function BoostSliderValueChanged(app, event)
                FilterPlot(app, event);
            
        end

        % Value changed function: QFacSlider
        function QFacSliderValueChanged(app, event)
                 FilterPlot(app, event);
            
        end

        % Value changed function: kDropDown_2
        function kDropDown_2ValueChanged(app, event)

            Selection = app.kDropDown_2.Value;
            disp(Selection);
            switch Selection
                case 1
                    disp("nothing selected");
                
                case 2
                    
                    app.FileString = '63378__casualsamples__bajos-22.wav';
                    app.AudioFs = 48000;
                    
                case 3
                    
                    app.FileString = '419028__realdavidfloat__kick-snare-loops-reverb-variations-120bpm.wav';
                    app.AudioFs = 44100;
                case 4
                    
                    app.FileString = '448213__florianreichelt__white-noise.wav';
                    app.AudioFs = 48000;
                case 5
                    
                    app.FileString = '440737__lamont-roberts__sweep001.wav';
                    app.AudioFs = 44100;
                otherwise
                 
                    app.FileString = '63378__casualsamples__bajos-22.wav';
                    app.AudioFs = 48000;
            end
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            AudioData = audioread(app.FileString);
            filteredsignal = filter(app.b,app.a,AudioData);
            player = audioplayer(filteredsignal, app.AudioFs);
            play(player);
            pause(app.DurationSlider.Value);
            stop(player);
            
        end

        % Callback function
        function StopButtonPushed(app, event)
            stop(player);
        end

        % Drop down opening function: kDropDown_2
        function kDropDown_2Opening(app, event)
            
        end

        % Value changed function: DurationSlider
        function DurationSliderValueChanged(app, event)
            Value = app.DurationSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.F0SliderLabel = uilabel(app.LeftPanel);
            app.F0SliderLabel.Text = 'F0';
            app.F0Slider = uislider(app.LeftPanel);
            app.F0Slider.Limits = [0 16000];
            app.F0Slider.ValueChangedFcn = createCallbackFcn(app, @F0SliderValueChanged, true);
            app.F0Slider.ValueChangingFcn = createCallbackFcn(app, @F0SliderValueChanging, true);
            app.F0Slider.Value = 8000;
            app.QFacSliderLabel = uilabel(app.LeftPanel);
            app.QFacSliderLabel.Text = 'QFac';
            app.QFacSlider = uislider(app.LeftPanel);
            app.QFacSlider.Limits = [0 30];
            app.QFacSlider.ValueChangedFcn = createCallbackFcn(app, @QFacSliderValueChanged, true);
            app.QFacSlider.ValueChangingFcn = createCallbackFcn(app, @QFacSliderValueChanging, true);
            app.QFacSlider.Value = 2;
            app.BoostSliderLabel = uilabel(app.LeftPanel);
            app.BoostSliderLabel.Text = 'Boost';
            app.BoostSlider = uislider(app.LeftPanel);
            app.BoostSlider.Limits = [-20 20];
            app.BoostSlider.ValueChangedFcn = createCallbackFcn(app, @BoostSliderValueChanged, true);
            app.BoostSlider.ValueChangingFcn = createCallbackFcn(app, @BoostSliderValueChanging, true);
            app.BoostSlider.MinorTicks = [-20 -16 -12 -8 -4 0 4 8 12 16 20];
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.kDropDown_2 = uidropdown(app.LeftPanel);
            app.kDropDown_2.Items = {'Select a sound clip', 'Bass_guitar', 'Kick Snare', 'White noise', 'Frequency Sweep (loud)'};
            app.kDropDown_2.ItemsData = [1 2 3 4.5];
            app.kDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @kDropDown_2Opening, true);
            app.kDropDown_2.ValueChangedFcn = createCallbackFcn(app, @kDropDown_2ValueChanged, true);
            app.kDropDown_2.Value = 1;
            app.DurationSliderLabel = uilabel(app.LeftPanel);
            app.DurationSliderLabel.Text = 'Duration';
            app.DurationSlider = uislider(app.LeftPanel);
            app.DurationSlider.Limits = [0 30];
            app.DurationSlider.ValueChangedFcn = createCallbackFcn(app, @DurationSliderValueChanged, true);
            app.HzLabel = uilabel(app.LeftPanel);
            app.HzLabel.Text = 'Hz';
            app.dBLabel = uilabel(app.LeftPanel);
            app.dBLabel.Text = 'dB';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Frequency Response ')
            xlabel(app.UIAxes, 'Frequency KHz')
            ylabel(app.UIAxes, 'Magnitude dBs')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 30000];
            app.UIAxes.YLim = [-20 20];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilterPlotting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end